classdef PolarPlotAppExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        GridLayout2      matlab.ui.container.GridLayout
        CONFIGUREButton  matlab.ui.control.Button
        STOPButton       matlab.ui.control.Button
        RADARPanel       matlab.ui.container.Panel
    end

    
    properties (Access = public)
        Pax % Polar axes
        status = 0 % 1 for playing, 0 for stopped
        dataQ % Data queue
        wk %worker
        jb %job
        tsk %task
        listener %listener
        stflag = 0
        polars = [2.1119,0.1436;
            0.9826,0.1482;
            2.0691,0.2842;
            1.0263,0.2842;
            1.5737,0.2373;
            2.0833,0.5186;
            1.0318,0.5186;
            1.5737,0.5186;
            1.5853,0.7529;
            1.6319,0.9003]
        answer
        hobj
        pool
        f
    end
    
    methods (Access = private)
        
        function updateplot(app,data)
            delete(app.hobj);
            app.hobj = polarscatter(app.Pax, data(:,1),data(:,2),40,'filled');
            app.Pax.ThetaLim = [0 180];
            app.Pax.RLim = [0 1];
            app.Pax.Position = [0 0 1 1];
            app.Pax.ThetaTickLabel = [];
            app.Pax.RTickLabel = [];
            return;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % parallel
            app.dataQ = parallel.pool.DataQueue;
            % Create polar axes and position it in pixels
            app.Pax = polaraxes(app.RADARPanel);
            app.Pax.Units = 'pixels';
            app.Pax.Units = 'normalized';
            polarscatter(app.Pax, app.polars(:,1), app.polars(:,2));
            app.Pax.ThetaLim = [0 180];
            app.Pax.RLim = [0 1];
            app.Pax.Position = [0 0 1 1];
            app.Pax.ThetaTickLabel = [];
            app.Pax.RTickLabel = [];
            hold(app.Pax, 'on');
            movegui(app.UIFigure,"center");
            % Plot the polar function
        end

        % Button pushed function: CONFIGUREButton
        function CONFIGUREButtonPushed(app, event)
            if app.stflag == 0
                prompt = {'Enter comportStandardNum','Enter comportEnhancedNum'};
                dlgtitle = 'Input';
                dims = [1 35];
                definput = {'4','3'};
                app.answer = inputdlg(prompt,dlgtitle,dims,definput);
                code(app.dataQ,str2double(app.answer{1}),str2double(app.answer{2}),1);
                app.CONFIGUREButton.Text = 'START';
                app.stflag = 1;
            elseif app.status == 0
                if isempty(gcp('nocreate'))
                    app.wk = parpool(1);
                end
                
                QQ = app.dataQ;
                a = str2double(app.answer{1});
                b = str2double(app.answer{2});
                app.f = parfeval(@code,0,QQ,a,b,0);
                %                 spmd
                %                     code(QQ,a,b,0);
                %                 end
                app.listener = afterEach(app.dataQ,@(data) updateplot(app,data));
                app.status = 1;
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            if app.status == 1
                delete(gcp("nocreate"));
                delete(app.listener);
                app.status = 0;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            delete(gcp("nocreate"));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Polar Plot';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CONFIGUREButton = uibutton(app.GridLayout2, 'push');
            app.CONFIGUREButton.ButtonPushedFcn = createCallbackFcn(app, @CONFIGUREButtonPushed, true);
            app.CONFIGUREButton.Text = 'CONFIGURE';
            app.STOPButton = uibutton(app.GridLayout2, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.RADARPanel = uipanel(app.GridLayout);
            app.RADARPanel.Title = 'RADAR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PolarPlotAppExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end